classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NotchSizeSpinner                matlab.ui.control.Spinner
        NotchSizeSpinnerLabel           matlab.ui.control.Label
        ThresholdSpinner                matlab.ui.control.Spinner
        ThresholdSpinnerLabel           matlab.ui.control.Label
        PeriodicNoiseReductionOptionLabel  matlab.ui.control.Label
        dalphatrimmedmeanSpinner        matlab.ui.control.Spinner
        dalphatrimmedmeanSpinnerLabel   matlab.ui.control.Label
        QcontraharmonicmeanSpinner      matlab.ui.control.Spinner
        QcontraharmonicmeanSpinnerLabel  matlab.ui.control.Label
        SigmaspatialsmoothSpinner       matlab.ui.control.Spinner
        SigmaspatialsmoothSpinnerLabel  matlab.ui.control.Label
        FiltersizespatialsmoothSpinner  matlab.ui.control.Spinner
        FiltersizespatialsmoothSpinnerLabel  matlab.ui.control.Label
        NoiseCheckBox                   matlab.ui.control.CheckBox
        BlurEffectDropDown              matlab.ui.control.DropDown
        BlurEffectDropDownLabel         matlab.ui.control.Label
        thetaSpinner                    matlab.ui.control.Spinner
        thetaSpinnerLabel               matlab.ui.control.Label
        lenSpinner                      matlab.ui.control.Spinner
        lenSpinnerLabel                 matlab.ui.control.Label
        DeconvolutionOptionLabel        matlab.ui.control.Label
        deconvCheckbox                  matlab.ui.control.CheckBox
        BrightnessfactorSpinner         matlab.ui.control.Spinner
        BrightnessfactorSpinnerLabel    matlab.ui.control.Label
        nforbutterwothSpinner           matlab.ui.control.Spinner
        nforbutterwothSpinnerLabel      matlab.ui.control.Label
        D0Spinner                       matlab.ui.control.Spinner
        D0SpinnerLabel                  matlab.ui.control.Label
        ConvolutioalMaskTextArea        matlab.ui.control.TextArea
        ConvolutioalMaskTextAreaLabel   matlab.ui.control.Label
        Matrix                          matlab.ui.control.Label
        FileName                        matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
        MatrixLabel                     matlab.ui.control.Label
        FilterButtonGroup               matlab.ui.container.ButtonGroup
        AlphatrimmedmeanButton          matlab.ui.control.RadioButton
        MidpointButton                  matlab.ui.control.RadioButton
        ContraharmonicmeanButton        matlab.ui.control.RadioButton
        HarmonicmeanButton              matlab.ui.control.RadioButton
        GeometricmeanButton             matlab.ui.control.RadioButton
        ArithmeticmeanButton            matlab.ui.control.RadioButton
        MedianButton                    matlab.ui.control.RadioButton
        MaxButton                       matlab.ui.control.RadioButton
        MinButton                       matlab.ui.control.RadioButton
        MeanButton                      matlab.ui.control.RadioButton
        ButterworthButton               matlab.ui.control.RadioButton
        GaussianButton                  matlab.ui.control.RadioButton
        IdealButton                     matlab.ui.control.RadioButton
        DropDown                        matlab.ui.control.DropDown
        ImageSelectedLabel              matlab.ui.control.Label
        SelectImageButton               matlab.ui.control.Button
        OutputImagePanel_2              matlab.ui.container.Panel
        InputFourierImage               matlab.ui.control.UIAxes
        OutputImagePanel                matlab.ui.container.Panel
        OutputFourierImage              matlab.ui.control.UIAxes
        OutputLibrary                   matlab.ui.control.UIAxes
        OutputImage                     matlab.ui.control.UIAxes
        InputImagePanel                 matlab.ui.container.Panel
        InputImage                      matlab.ui.control.UIAxes
        PenapisanCitraLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        ImageInput % Description
        ImageOutput
        ConvolutionMatrix
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            % Pilih file gambar menggunakan uigetfile
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'});
    
            % Jika pengguna memilih file
            if isequal(file, 0)
                disp('User cancelled the operation');
            else
                cla(app.InputImage);
                % Baca gambar
                img = imread(fullfile(path, file));
                app.FileName.Text = file;
        
                % Tampilkan gambar di UIAxes
                imshow(img, 'Parent', app.InputImage);
                app.ImageInput = img;

                cla(app.InputFourierImage);
                cla(app.OutputFourierImage);
                cla(app.OutputImage);
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            value = app.DropDown.Value;

            mat = app.ConvolutioalMaskTextArea.Value;
            matrix = strjoin(mat, ' ');
            app.Matrix.Text = matrix;
            switch value
                case 'Convolution'
                    outputImg = convolution(double(app.ImageInput),double(str2num(matrix)));
                    imshow(outputImg, 'Parent', app.OutputImage);

                    outputLibrary = convolutionLibrary(app.ImageInput,str2num(matrix));
                    imshow(outputLibrary,'Parent',app.OutputLibrary);
                case 'High-pass Filtering'
                    filter = app.FilterButtonGroup.SelectedObject.Text;

                    outputImg = highPassFilter(app.ImageInput,filter,app.D0Spinner.Value,app.nforbutterwothSpinner.Value);
                    imshow(outputImg, 'Parent', app.OutputImage);

                    fourierSpectrum = showFourier(app.ImageInput);
                    imshow(fourierSpectrum, 'Parent',app.InputFourierImage);

                    fourierSpectrum = showFourier(outputImg);
                    imshow(fourierSpectrum, 'Parent',app.OutputFourierImage);
                case 'Spatial Smoothing'
                    fourierSpectrum = showFourier(app.ImageInput);
                    imshow(fourierSpectrum, 'Parent',app.InputFourierImage);

                    filter = app.FilterButtonGroup.SelectedObject.Text;

                    outputImg = spatialSmooth(app.ImageInput,filter,app.FiltersizespatialsmoothSpinner.Value,app.SigmaspatialsmoothSpinner.Value);
                    imshow(outputImg, 'Parent', app.OutputImage);

                    fourierSpectrum = showFourier(outputImg);
                    imshow(fourierSpectrum, 'Parent',app.OutputFourierImage);
                case 'Low-pass Filtering'
                    fourierSpectrum = showFourier(app.ImageInput);
                    imshow(fourierSpectrum, 'Parent',app.InputFourierImage);

                    filter = app.FilterButtonGroup.SelectedObject.Text;

                    outputImg = lowPassFilter(app.ImageInput,filter,app.D0Spinner.Value,app.nforbutterwothSpinner.Value);
                    imshow(outputImg, 'Parent', app.OutputImage);

                    fourierSpectrum = showFourier(outputImg);
                    imshow(fourierSpectrum, 'Parent',app.OutputFourierImage);
                case 'Image Brightening'
                    outputImg = brightening(app.ImageInput,app.BrightnessfactorSpinner.Value);

                    imshow(outputImg, 'Parent', app.OutputImage);

                    fourierSpectrum = showFourier(app.ImageInput);
                    imshow(fourierSpectrum, 'Parent',app.InputFourierImage);

                    fourierSpectrum = showFourier(outputImg);
                    imshow(fourierSpectrum, 'Parent',app.OutputFourierImage);
                case 'Noise Reduction (Salt and Pepper)'
                    filter = app.FilterButtonGroup.SelectedObject.Text;
                    noisyImg = saltPepperNoise(app.ImageInput, 0.05);
                    if (lower(filter) == "contraharmonic mean")
                        outputImg = noiseFiltering(app.ImageInput, filter, app.QcontraharmonicmeanSpinner.Value);
                    elseif (lower(filter) == "alpha-trimmed mean")
                        outputImg = noiseFiltering(app.ImageInput, filter, app.dalphatrimmedmeanSpinner.Value);
                    else
                        outputImg = noiseFiltering(app.ImageInput,filter);
                    end

                    imshow(outputImg, 'Parent', app.OutputImage);
                    imshow(noisyImg, 'Parent',app.OutputLibrary);
                    title(app.OutputLibrary, 'Noisy Input Image');
                case 'Noise Reduction (Periodic)'
                    outputImg = autoPeriodicNoiseReduction(app.ImageInput,app.NotchSizeSpinner.Value,app.ThresholdSpinner.Value);
                    
                    imshow(outputImg, 'Parent', app.OutputImage);

                    imageFFT = fftshift(fft2(app.ImageInput));
                    magnitudeFFT = abs(imageFFT);
                    
                    fourierSpectrum = log(1 + magnitudeFFT);  % Log scale to enhance visibility
                    fourierSpectrum = uint8(255 * mat2gray(fourierSpectrum));

                    imshow(fourierSpectrum, 'Parent',app.InputFourierImage);

                    outputImgFFT = fftshift(fft2(outputImg));
                    magnitudeFFT = abs(outputImgFFT);
                    
                    % Compute and normalize the Fourier Spectrum for display
                    outputFourierSpectrum = log(1 + magnitudeFFT);  % Log scale to enhance visibility
                    outputFourierSpectrum = uint8(255 * mat2gray(outputFourierSpectrum));

                    imshow(outputFourierSpectrum, 'Parent',app.OutputFourierImage);

                case 'Deconvolution'
                    if strcmp(app.BlurEffectDropDown.Value, 'Motion')
                        PSF = fspecial('motion', app.lenSpinner.Value, app.thetaSpinner.Value);
                    elseif strcmp(app.BlurEffectDropDown.Value, 'Gaussian')
                        PSF = fspecial('gaussian', app.lenSpinner.Value, app.thetaSpinner.Value); % Parameter THETA diabaikan untuk Gaussian
                    else
                        error('blurEffect harus bernilai "motion" atau "gaussian".');
                    end

                    outputImg = deconvolution(im2double(app.ImageInput), app.deconvCheckbox.Value, app.NoiseCheckBox.Value, PSF, app.lenSpinner.Value, app.BlurEffectDropDown.Value);
                    disp(app.deconvCheckbox.Value);
                    if (app.deconvCheckbox.Value)
                        image = motionBluring(app.ImageInput,PSF);
                        imshow(image, 'Parent', app.OutputLibrary);

                        app.OutputLibrary.Title.String = 'Blurred Image';
                    end

                    fourierSpectrum = showFourier(app.ImageInput);
                    imshow(fourierSpectrum, 'Parent',app.InputFourierImage);

                    fourierSpectrum = showFourier(outputImg);
                    imshow(fourierSpectrum, 'Parent',app.OutputFourierImage);

                    imshow(outputImg, 'Parent', app.OutputImage);
            end
        end

        % Selection changed function: FilterButtonGroup
        function FilterButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilterButtonGroup.SelectedObject;
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            cla(app.OutputImage);
            cla(app.OutputLibrary);
            cla(app.InputImage);
        end

        % Value changed function: BlurEffectDropDown
        function BlurEffectDropDownValueChanged(app, event)
            value = app.BlurEffectDropDown.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PenapisanCitraLabel = uilabel(app.UIFigure);
            app.PenapisanCitraLabel.Text = 'Penapisan Citra';
            app.InputImagePanel = uipanel(app.UIFigure);
            app.InputImagePanel.Title = 'Input Image';
            app.InputImage = uiaxes(app.InputImagePanel);
            title(app.InputImage, 'Input Image')
            zlabel(app.InputImage, 'Z')
            app.InputImage.XTick = [0 0.5 1];
            app.InputImage.XTickLabel = '';
            app.InputImage.YTickLabel = '';
            app.OutputImagePanel = uipanel(app.UIFigure);
            app.OutputImagePanel.Title = 'Output Image';
            app.OutputImage = uiaxes(app.OutputImagePanel);
            title(app.OutputImage, 'Output Image')
            zlabel(app.OutputImage, 'Z')
            app.OutputImage.XTick = [0 0.5 1];
            app.OutputImage.XTickLabel = '';
            app.OutputImage.YTickLabel = '';
            app.OutputLibrary = uiaxes(app.OutputImagePanel);
            title(app.OutputLibrary, 'Output with library')
            zlabel(app.OutputLibrary, 'Z')
            app.OutputLibrary.XTick = [0 0.5 1];
            app.OutputLibrary.XTickLabel = '';
            app.OutputLibrary.YTickLabel = '';
            app.OutputFourierImage = uiaxes(app.OutputImagePanel);
            title(app.OutputFourierImage, 'Output Fourier Spectrum')
            zlabel(app.OutputFourierImage, 'Z')
            app.OutputFourierImage.XTick = [0 0.5 1];
            app.OutputFourierImage.XTickLabel = '';
            app.OutputFourierImage.YTickLabel = '';
            app.OutputImagePanel_2 = uipanel(app.UIFigure);
            app.OutputImagePanel_2.Title = 'Output Image';
            app.InputFourierImage = uiaxes(app.OutputImagePanel_2);
            title(app.InputFourierImage, 'Input Fourier Spectrum')
            zlabel(app.InputFourierImage, 'Z')
            app.InputFourierImage.XTick = [0 0.5 1];
            app.InputFourierImage.XTickLabel = '';
            app.InputFourierImage.YTickLabel = '';
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.ImageSelectedLabel = uilabel(app.UIFigure);
            app.ImageSelectedLabel.Text = 'Image Selected:';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Convolution', 'Spatial Smoothing', 'Low-pass Filtering', 'High-pass Filtering', 'Image Brightening', 'Noise Reduction (Salt and Pepper)', 'Noise Reduction (Periodic)', 'Deconvolution'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Convolution';
            app.FilterButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterButtonGroupSelectionChanged, true);
            app.FilterButtonGroup.Title = 'Filter';
            app.IdealButton = uiradiobutton(app.FilterButtonGroup);
            app.IdealButton.Text = 'Ideal';
            app.IdealButton.Value = true;
            app.GaussianButton = uiradiobutton(app.FilterButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.ButterworthButton = uiradiobutton(app.FilterButtonGroup);
            app.ButterworthButton.Text = 'Butterworth';
            app.MeanButton = uiradiobutton(app.FilterButtonGroup);
            app.MeanButton.Text = 'Mean';
            app.MinButton = uiradiobutton(app.FilterButtonGroup);
            app.MinButton.Text = 'Min';
            app.MaxButton = uiradiobutton(app.FilterButtonGroup);
            app.MaxButton.Text = 'Max';
            app.MedianButton = uiradiobutton(app.FilterButtonGroup);
            app.MedianButton.Text = 'Median';
            app.ArithmeticmeanButton = uiradiobutton(app.FilterButtonGroup);
            app.ArithmeticmeanButton.Text = 'Arithmetic mean';
            app.GeometricmeanButton = uiradiobutton(app.FilterButtonGroup);
            app.GeometricmeanButton.Text = 'Geometric mean';
            app.HarmonicmeanButton = uiradiobutton(app.FilterButtonGroup);
            app.HarmonicmeanButton.Text = 'Harmonic mean';
            app.ContraharmonicmeanButton = uiradiobutton(app.FilterButtonGroup);
            app.ContraharmonicmeanButton.Text = 'Contraharmonic mean';
            app.MidpointButton = uiradiobutton(app.FilterButtonGroup);
            app.MidpointButton.Text = 'Midpoint';
            app.AlphatrimmedmeanButton = uiradiobutton(app.FilterButtonGroup);
            app.AlphatrimmedmeanButton.Text = 'Alpha-trimmed mean';
            app.MatrixLabel = uilabel(app.UIFigure);
            app.MatrixLabel.Text = 'Matrix:';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.FileName = uilabel(app.UIFigure);
            app.FileName.Text = '---';
            app.Matrix = uilabel(app.UIFigure);
            app.Matrix.Text = '-';
            app.ConvolutioalMaskTextAreaLabel = uilabel(app.UIFigure);
            app.ConvolutioalMaskTextAreaLabel.Text = 'Convolutioal Mask';
            app.ConvolutioalMaskTextArea = uitextarea(app.UIFigure);
            app.D0SpinnerLabel = uilabel(app.UIFigure);
            app.D0SpinnerLabel.Text = 'D0';
            app.D0Spinner = uispinner(app.UIFigure);
            app.D0Spinner.Value = 50;
            app.nforbutterwothSpinnerLabel = uilabel(app.UIFigure);
            app.nforbutterwothSpinnerLabel.Text = 'n (for butterwoth)';
            app.nforbutterwothSpinner = uispinner(app.UIFigure);
            app.nforbutterwothSpinner.Value = 1;
            app.BrightnessfactorSpinnerLabel = uilabel(app.UIFigure);
            app.BrightnessfactorSpinnerLabel.Text = 'Brightness factor';
            app.BrightnessfactorSpinner = uispinner(app.UIFigure);
            app.deconvCheckbox = uicheckbox(app.UIFigure);
            app.deconvCheckbox.Text = 'Include motion bluring on image';
            app.DeconvolutionOptionLabel = uilabel(app.UIFigure);
            app.DeconvolutionOptionLabel.Text = 'Deconvolution Option';
            app.lenSpinnerLabel = uilabel(app.UIFigure);
            app.lenSpinnerLabel.Text = 'len';
            app.lenSpinner = uispinner(app.UIFigure);
            app.thetaSpinnerLabel = uilabel(app.UIFigure);
            app.thetaSpinnerLabel.Text = 'theta';
            app.thetaSpinner = uispinner(app.UIFigure);
            app.BlurEffectDropDownLabel = uilabel(app.UIFigure);
            app.BlurEffectDropDownLabel.Text = 'Blur Effect';
            app.BlurEffectDropDown = uidropdown(app.UIFigure);
            app.BlurEffectDropDown.Items = {'Motion', 'Gaussian'};
            app.BlurEffectDropDown.ValueChangedFcn = createCallbackFcn(app, @BlurEffectDropDownValueChanged, true);
            app.BlurEffectDropDown.Value = 'Motion';
            app.NoiseCheckBox = uicheckbox(app.UIFigure);
            app.NoiseCheckBox.Text = 'Noise';
            app.FiltersizespatialsmoothSpinnerLabel = uilabel(app.UIFigure);
            app.FiltersizespatialsmoothSpinnerLabel.Text = 'Filter size (spatial smooth)';
            app.FiltersizespatialsmoothSpinner = uispinner(app.UIFigure);
            app.FiltersizespatialsmoothSpinner.Value = 5;
            app.SigmaspatialsmoothSpinnerLabel = uilabel(app.UIFigure);
            app.SigmaspatialsmoothSpinnerLabel.Text = 'Sigma (spatial smooth)';
            app.SigmaspatialsmoothSpinner = uispinner(app.UIFigure);
            app.SigmaspatialsmoothSpinner.Step = 0.5;
            app.SigmaspatialsmoothSpinner.Value = 1.5;
            app.QcontraharmonicmeanSpinnerLabel = uilabel(app.UIFigure);
            app.QcontraharmonicmeanSpinnerLabel.Text = 'Q (contraharmonic mean)';
            app.QcontraharmonicmeanSpinner = uispinner(app.UIFigure);
            app.QcontraharmonicmeanSpinner.Step = 0.5;
            app.QcontraharmonicmeanSpinner.Value = 1.5;
            app.dalphatrimmedmeanSpinnerLabel = uilabel(app.UIFigure);
            app.dalphatrimmedmeanSpinnerLabel.Text = 'd (alpha-trimmed mean)';
            app.dalphatrimmedmeanSpinner = uispinner(app.UIFigure);
            app.dalphatrimmedmeanSpinner.Value = 1;
            app.PeriodicNoiseReductionOptionLabel = uilabel(app.UIFigure);
            app.PeriodicNoiseReductionOptionLabel.Text = 'Periodic Noise Reduction Option';
            app.ThresholdSpinnerLabel = uilabel(app.UIFigure);
            app.ThresholdSpinnerLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.UIFigure);
            app.ThresholdSpinner.Step = 0.1;
            app.ThresholdSpinner.Value = 0.5;
            app.NotchSizeSpinnerLabel = uilabel(app.UIFigure);
            app.NotchSizeSpinnerLabel.Text = 'Notch Size';
            app.NotchSizeSpinner = uispinner(app.UIFigure);
            app.NotchSizeSpinner.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end